classdef Omar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        RGBTOGRAYButton       matlab.ui.control.Button
        APPLYButton           matlab.ui.control.Button
        Input1EditField       matlab.ui.control.NumericEditField
        XEditFieldLabel       matlab.ui.control.Label
        Input2EditField       matlab.ui.control.NumericEditField
        YEditFieldLabel       matlab.ui.control.Label
        RESETButton           matlab.ui.control.Button
        UPLOADButton          matlab.ui.control.Button
        FiltersDropDown       matlab.ui.control.DropDown
        FiltersDropDownLabel  matlab.ui.control.Label
        UIAxes_2              matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
       originalImage % Description
       editedImage
       resetImage       
    end
    
    methods (Access = private)
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UPLOADButton
        function UPLOADButtonPushed(app, event)
        %     [file,path]=uigetfile({'*.png';'*.jpg';'*.gif';'*.tiff'}, 'Select file');
        % app.originalImage=imread([path file]);
        % imshow(app.originalImage,'parent', app.UIAxes)
        global image;
        global arr;
        global counter;
        try
            [filename1,filepath1] = uigetfile({'*.*'},'Select Data File 1');
            cd(filepath1);
            image=imread([filepath1 filename1]);
            counter=1;
            arr = cell(1,100);
            app.UIAxes.Visible = 'on';
            imshow(image, 'Parent', app.UIAxes);
            arr{counter}=image;
        catch
            uialert(app.UIFigure,["No Image has been selected"; ...
                            "Please select an Image"],'Invalid choice');
        end
        clear uploadedImage;
        app.UIAxes_2.Visible = 'off';
        end

        % Value changed function: FiltersDropDown
        function FiltersDropDownValueChanged(app, event)
            value = app.FiltersDropDown.Value;
            switch value
                case "Subsampling" %1
                    app.Input1EditField.Visible = 'on';
                    app.XEditFieldLabel.Visible = 'on';
                    app.Input2EditField.Visible = 'off';
                    app.YEditFieldLabel.Visible = 'off';
                case "Upsampling" %2
                    app.Input1EditField.Visible = 'on';
                    app.XEditFieldLabel.Visible = 'on';
                    app.Input2EditField.Visible = 'off';
                    app.YEditFieldLabel.Visible = 'off';
                case "Gray Scale" %3
                    app.Input1EditField.Visible = 'on';
                    app.XEditFieldLabel.Visible = 'on';
                    app.Input2EditField.Visible = 'off';
                    app.YEditFieldLabel.Visible = 'off';
                case "Log" %7
                    app.Input1EditField.Visible = 'on';
                    app.XEditFieldLabel.Visible = 'on';
                    app.Input2EditField.Visible = 'off';
                    app.YEditFieldLabel.Visible = 'off';
                case "inverse Log" %8
                    app.Input1EditField.Visible = 'on';
                    app.XEditFieldLabel.Visible = 'on';
                    app.Input2EditField.Visible = 'off';
                    app.YEditFieldLabel.Visible = 'off';
                case "Power Low" %9
                    app.Input1EditField.Visible = 'on';
                    app.XEditFieldLabel.Visible = 'on';
                    app.Input2EditField.Visible = 'on';
                    app.YEditFieldLabel.Visible = 'on';
                case "Contrast stretching" %10
                    app.Input1EditField.Visible = 'on';
                    app.XEditFieldLabel.Visible = 'on';
                    app.Input2EditField.Visible = 'on';
                    app.YEditFieldLabel.Visible = 'on';
                case "Theashould 0,1" %11
                    app.Input1EditField.Visible = 'on';
                    app.XEditFieldLabel.Visible = 'on';
                    app.Input2EditField.Visible = 'off';
                    app.YEditFieldLabel.Visible = 'off';
                case " Gray slicing AP#1" %12
                    app.Input1EditField.Visible = 'on';
                    app.XEditFieldLabel.Visible = 'on';
                    app.Input2EditField.Visible = 'on';
                    app.YEditFieldLabel.Visible = 'on';
                case " Gray slicing AP#2" %13
                    app.Input1EditField.Visible = 'on';
                    app.XEditFieldLabel.Visible = 'on';
                    app.Input2EditField.Visible = 'on';
                    app.YEditFieldLabel.Visible = 'on';
                case "Addition " %15
                    app.Input1EditField.Visible = 'on';
                    app.XEditFieldLabel.Visible = 'on';
                    app.Input2EditField.Visible = 'off';
                    app.YEditFieldLabel.Visible = 'off';
                 case "Multiplication" %15
                    app.Input1EditField.Visible = 'on';
                    app.XEditFieldLabel.Visible = 'on';
                    app.Input2EditField.Visible = 'off';
                    app.YEditFieldLabel.Visible = 'off'; 
                 case "Division" %15
                    app.Input1EditField.Visible = 'on';
                    app.XEditFieldLabel.Visible = 'on';
                    app.Input2EditField.Visible = 'off';
                    app.YEditFieldLabel.Visible = 'off';    
                 case "Subtraction "
                    
                    app.Input1EditField.Visible = 'on';
                    app.XEditFieldLabel.Visible = 'on';
                    app.Input2EditField.Visible = 'off';
                    app.YEditFieldLabel.Visible = 'off';
             
                case "High pass filter (ideal both)" %27
                    app.Input1EditField.Visible = 'on';
                    app.XEditFieldLabel.Visible = 'on';
                    app.Input2EditField.Visible = 'off';
                    app.YEditFieldLabel.Visible = 'off';
                case "High pass filter (Butterworth both)"
                    app.Input1EditField.Visible = 'on';
                    app.XEditFieldLabel.Visible = 'on';
                    app.Input2EditField.Visible = 'on';
                    app.YEditFieldLabel.Visible = 'on';
                case "High pass filter (gaussian both)" %29
                    app.Input1EditField.Visible = 'on';
                    app.XEditFieldLabel.Visible = 'on';
                    app.Input2EditField.Visible = 'off';
                    app.YEditFieldLabel.Visible = 'off';
                case "Low pass filter (ideal both)" %30
                    app.Input1EditField.Visible = 'on';
                    app.XEditFieldLabel.Visible = 'on';
                    app.Input2EditField.Visible = 'off';
                    app.YEditFieldLabel.Visible = 'off';
                case "Low pass filter (Butterworth both)"
                    app.Input1EditField.Visible = 'on';
                    app.XEditFieldLabel.Visible = 'on';
                    app.Input2EditField.Visible = 'on';
                    app.YEditFieldLabel.Visible = 'on';
                case "Low pass filter (gaussian both)" %32
                    app.Input1EditField.Visible = 'on';
                    app.XEditFieldLabel.Visible = 'on';
                    app.Input2EditField.Visible = 'off';
                    app.YEditFieldLabel.Visible = 'off';
                otherwise
                    app.Input1EditField.Visible = 'off';
                    app.XEditFieldLabel.Visible = 'off';
                    app.Input2EditField.Visible = 'off';
                    app.YEditFieldLabel.Visible = 'off';
            end
            
        end

        % Button pushed function: APPLYButton
        function APPLYButtonPushed(app, event)
            global arr;
            global counter;
            global img;
            Feature=app.FiltersDropDown.Value;
            if(counter>1)
                
            end
            switch Feature
                case "Upsampling" 
                    newImage = arr{counter};
                    upsampleFactor = app.Input1EditField.Value;
                    [rows, cols, channels] = size(newImage);
                    rows
                    cols
                   outRows = rows * upsampleFactor;
                   outCols = cols * upsampleFactor;
                   outImage = zeros(outRows, outCols, channels, class(newImage)); 
                   for k = 1:channels
                      for i = 1:outRows
                         for j = 1:outCols
                               inputRow = round(i/upsampleFactor);
                               inputCol = round(j/upsampleFactor);
                               inputRow = min(max(inputRow, 1), rows);
                               inputCol = min(max(inputCol, 1), cols);
                               outImage(i, j, k) = newImage(inputRow, inputCol, k);
                          end
                      end 
                   end
                   [rows, cols] = size(outImage);
                   rows
                   cols
                    imshow(outImage, 'Parent', app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=outImage;
                    case "Subsampling"
                    newImage = arr{counter};
                    [rows, cols, matricesNo] = size(newImage);
                    SamplingFactor = app.Input1EditField.Value;
                    rows
                    cols
                    for metricesIndex=1:1:matricesNo
                         resizedImage(:,:,metricesIndex) = subSampling(newImage(:,:,metricesIndex),SamplingFactor);
                    end
                    [rows, cols] = size(resizedImage);
                     rows 
                     cols
                    imshow(resizedImage, 'Parent', app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=resizedImage;
                case "negative" 
                    neg = 255 - arr{counter};
                    imshow(neg, 'Parent', app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=neg;
                case "Log" 
                    c=app.Input1EditField.Value;
                    doubleImage=im2double(arr{counter});
                    s=(c*log(1+doubleImage))*256;
                    s1=uint8(s);
                    imshow(s1, 'Parent', app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=s1;
                case "inverse Log" 
                    L=app.Input1EditField.Value;
                    doubleImage=double(arr{counter});
                    exp_I = uint8(exp(doubleImage/L)-1);
                    imshow(exp_I, 'Parent', app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=exp_I;
                case "Power Low" 
                    c = app.Input1EditField.Value;
                    p = app.Input2EditField.Value;
                    x1 = double(arr{counter});
                    y = c * power(x1, p);
                    output_img = uint8(y);
                    imshow(output_img, 'Parent', app.UIAxes_2);
                    arr{counter}=output_img;
                
                case "Contrast stretching" 
                    a = arr{counter};
                    c = app.Input1EditField.Value;
                    p = app.Input2EditField.Value;
                   [rows, cols] = size(a);
                    maxx=max(max(a(:,:)));
                    minn=min(min(a(:,:)));
                    out=a;
                    for i = 1:rows
                       for j = 1:cols
                           out(i,j)=((p-c)/(maxx-minn))*(a(i,j)-minn)+c;
                       end
                    end 
                    counter=counter+1;
                    arr{counter}=out;
                    imshow((out),'Parent',app.UIAxes_2); 
                case "Theashould 0,1" 
                    newImage = arr{counter};    
                    T= app.Input1EditField.Value;
                    [rows, cols] = size(newImage);
                    for row_index=1:1:rows
                        for col_index=1:1:cols
                            if(newImage(row_index,col_index) >= T)
                                newImage(row_index,col_index) = 255;
                            else
                                 newImage(row_index,col_index) = 0;
                            end
                        end
                    end
                    imshow(newImage, 'Parent', app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=newImage;
                case 'Grey Level Resolution'
                    editedImg=arr{counter};
                    k = 8;
                    figure
                    while (k > 0)
                     target_levels = 2^k;
                     target_compr_factor = 256 / target_levels;
                     img = uint8(floor(double(editedImg)/256 * target_levels) * target_compr_factor);
                     subplot(3, 3, k);
                     imshow(img, [0 255]);
                    %   imshow(app.editedImg(:,:,k));
                     title(['Grey-level resolution 2^',num2str(k)]);
                     k = k - 1;
                    end    

                case " Gray slicing AP#1"
                    newImage = arr{counter}; 
                    sm= app.Input1EditField.Value;
                    sM= app.Input2EditField.Value;
                    [rows, cols] = size(newImage);
                    for row_index=1:1:rows
                        for col_index=1:1:cols
                            if(newImage(row_index,col_index)>=sm && newImage(row_index,col_index)<=sM)
                                newImage(row_index,col_index) = 255;
                            else
                                 newImage(row_index,col_index) = 0;
                            end
                        end
                    end
                    imshow(newImage, 'Parent', app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=newImage;
                case " Gray slicing AP#2" 
                    newImage = arr{counter}; 
                    sm= app.Input1EditField.Value;
                    sM= app.Input2EditField.Value;
                    [rows, cols] = size(newImage);
                    for row_index=1:1:rows
                        for col_index=1:1:cols
                            if(newImage(row_index,col_index)>=sm && newImage(row_index,col_index)<=sM)
                                newImage(row_index,col_index) = 255;
                            else
                                 newImage(row_index,col_index) = newImage(row_index,col_index);
                            end
                        end
                    end
                    imshow(newImage, 'Parent', app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=newImage;
                case "Bit plane slicing" 
                    editedImg = arr{counter};
                    [rows, cols] = size(editedImg);
                    x = zeros(rows,cols,8);
                    for k=1:8
                       for row_index=1:1:rows
                              for col_index=1:1:cols
                                  x(row_index,col_index,k)=bitget(editedImg(row_index,col_index),k);
                               end
                       end
                     subplot(3, 3, k);
                     imshow(x(:,:,k));
                     title(['Image for bit number ', num2str(k)]);
    
                    end
                case "Addition " 
                    Image1 = arr{counter};
                    ad = app.Input1EditField.Value;
                    answer = Image1 + ad;
                    imshow(answer,'Parent',app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=answer;
                case "Subtraction "
                    Image1 = arr{counter};
                    ad = app.Input1EditField.Value;
                    answer = Image1-ad;
                    imshow(answer,'Parent',app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=answer;
                case "Multiplication"
                     Image1 = arr{counter};
                    ad = app.Input1EditField.Value;
                    answer = Image1 * ad;
                    imshow(answer,'Parent',app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=answer;
                case "Division" 
                    Image1 = arr{counter};
                    ad = app.Input1EditField.Value;
                    answer = Image1 / ad;
                    imshow(answer,'Parent',app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=answer;
                case "Image Subtraction " %16

                    [filename1,filepath1] = uigetfile({'.'},'Select Data File 1');
                    cd(filepath1);
                    e=imread([filepath1 filename1]);
                    Image1 = arr{counter};
                    try
                        Image2 = rgb2gray(e);
                    catch
                    end
                    f=imresize(Image1,[size(Image2,1) size(Image2,2)]);
                    answer = f-Image2;
                    a=answer;
                    imshow(a,'Parent',app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=a;
                
                case 'Logic AND'
                   [filename1,filepath1] = uigetfile({'.png';'.jpg';'.gif';'.tiff'},'Select Data File 1');
                    cd(filepath1);
                   image1=imread([filepath1 filename1]);
                   image2=arr{counter};
                   [m, n] = size(image1);
                   [mm, nn] = size(image2);
                   new = image1;               
                   for i = 1:min(mm,m)
                       for j =1 : min(nn,n)
                           if (image1(i,j)>20 && image2(i,j)>20)
                                 new(i,j)=image2(i,j);
                           end
                       end
                   end
                   counter=counter+1;
                   arr{counter}=new;
                   imshow(new,'Parent',app.UIAxes_2);
                case 'Logic OR'
                   [filename1,filepath1] = uigetfile({'.png';'.jpg';'.gif';'.tiff'},'Select Data File 1');
                    cd(filepath1);
                   image1=imread([filepath1 filename1]);
                   image2=arr{counter};
                   [m, n] = size(image1);
                   [mm, nn] = size(image2);
                   new = image2;               
                   for i = 1:min(mm,m)
                       for j =1:min(nn,n)
                           if (image2(i,j)>image1(i,j))
                                 new(i,j)=image2(i,j);
                           else 
                                 new(i,j)=image1(i,j);
                           end
                       end
                   end
                   counter=counter+1;
                   arr{counter}=new;
                   imshow(new,'Parent',app.UIAxes_2);
                   case 'Logic AND 0'
                   image2=arr{counter};
                   [m, n] = size(image2);
                   new = image2;               
                   for i = 1:m
                       for j =1:n
                             new(i,j)=0;
                       end
                   end
                   counter=counter+1;
                   arr{counter}=new;
                   imshow(new,'Parent',app.UIAxes_2);
                   case 'Logic AND 1 / OR 0'
                   image1=arr{counter};
                   [m, n] = size(image1);
                   new = image1;               
                   for i = 1:m
                       for j =1:n
                           if(image1(i,j)<20)
                                new(i,j)=0;
                           end
                       end
                   end
                   counter=counter+1;
                   arr{counter}=new;
                   imshow(new,'Parent',app.UIAxes_2);
                   case 'Logic OR 1'
                   image2=arr{counter};
                   [m, n] = size(image2);
                   new = image2;               
                   for i = 1:m
                       for j =1:n
                           if(image2(i,j)<35)
                               new(i,j)=255;
                           end
                       end
                   end
                   counter=counter+1;
                   arr{counter}=new;
                   imshow(new,'Parent',app.UIAxes_2);
                  case "Histogram" %19
                    %global a;
                     a=arr{counter};
                    [rows,cols]=size(a);
                    counts=zeros(1,256);
                    for i=1:rows
                       for j=1:cols
                          grayLevel=a(i,j);
                          counts(grayLevel+1)=counts(grayLevel+1)+1;
                       end
                    end
                    grayLevels = 0 : 255;
                    bar(grayLevels, counts, 'BarWidth', 1, 'FaceColor', 'b');
                    title('Histogram');
                    xlabel('Gray Level');
                    ylabel('Counts');
                case "Histogram Equalization"
                    a=arr{counter};
                    numofpixels=size(a,1)*size(a,2);
                    HIm=uint8(zeros(size(a,1),size(a,2)));
                    freq=zeros(256,1);
                    probc=zeros(256,1);
                    output=zeros(256,1);
                    %freq counts the occurrence of each pixel value.
                    %The probability of each occurrence is calculated by probf.
                    for i=1:size(a,1)
                        for j=1:size(a,2)
                            value=a(i,j);
                            freq(value+1)=freq(value+1)+1;
                        end
                    end
                    summ=0;
                    no_bins=255;
                    %The cumulative distribution probability is calculated. 
                    for i=1:size(freq)
                       summ=summ+freq(i);
                       probc(i)=summ/numofpixels;
                       output(i)=round(probc(i)*no_bins);
                    end
                    for i=1:size(a,1)
                        for j=1:size(a,2)
                                HIm(i,j)=output(a(i,j)+1);
                        end
                    end
                    a=HIm;
                    imshow((a),'Parent',app.UIAxes_2)
                    counter=counter+1;
                    arr{counter}=a;
                    
                case "Smoothing(Avg Standerd)" %20
                    a=arr{counter};
                    gray_image = double(a);
                    [rows,cols]=size(gray_image);
                    out = gray_image;
                    for i=2:rows-1
                     for j=2:cols-1
                         temp = gray_image(i-1:i+1,j-1:j+1).*(1/(3*3));
                         value = sum(temp(:));
                         out(i, j)= value;
                     end
                    end
                    out = uint8(out);
                    a=out;
                    imshow((a),'Parent',app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=a;
                    
                case "Smoothing(Avg Weight)" %21
                     a=arr{counter};
                    gray_image = double(a);
                    [rows,cols]=size(gray_image);
                    mask = [(1/16),(2/16),(1/16);(2/16),(4/16),(2/16);(1/16),(2/16),(1/16)];
                    out = gray_image;
                    for i=2:rows-1
                     for j=2:cols-1
                         temp = mask.*gray_image(i-1:i+1,j-1:j+1);
                         value =sum(temp(:));
                         out(i, j)= value;
                    end
                    end
                    out = uint8(out);
                    a=out;
                    imshow((a),'Parent',app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=a;
                case "Smoothing(Median)" %22
                     a=arr{counter};
                    [rows,cols]=size(a);
                    out=a;
                    for i=2:rows-1
                     for j=2:cols-1
                         temp = [a(i-1, j-1) a(i-1, j) a(i-1, j + 1) a(i, j-1) a(i, j) a(i, j + 1) a(i + 1, j-1) a(i + 1, j) a(i + 1, j + 1)];
                         temp = sort(temp);
                         out(i, j)= temp(5);
                    end
                    end
                    a=out;
                    imshow((a),'Parent',app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=a;
                case "Smoothing(Mod)" %22
                    a=arr{counter};
                    gray_image = double(a);
                    [rows,cols]=size(gray_image);
                    out = gray_image;
                    for i=2:rows-1
                     for j=2:cols-1
                         neighborhood = gray_image(i-1:i+1, j-1:j+1);
                         out(i, j) = mode(neighborhood(:));
                     end
                    end
                    out = uint8(out);
                    a=out;
                    imshow((a),'Parent',app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=a;
                case "Sharpining(Laplacian)" %23
                    a=arr{counter};
                    gray_image = double(a);
                    [rows,cols]=size(gray_image);
                    mask = [0,1,0;1,-4,1;0,1,0];
                    out = gray_image;
                    for i=2:rows-1
                       for j=2:cols-1
                         temp = mask.*gray_image(i-1:i+1,j-1:j+1);
                         value = sum(temp(:));
                         out(i, j)= value;
                       end
                    end
                    out = uint8(out);
                    a=out;
                    imshow((a),'Parent',app.UIAxes_2)
                    title('sharp image');
                    counter=counter+1;
                    arr{counter}=a;
                    
                case "Sharpining(Comp Laplacian)" %24
                    a=arr{counter};
                    gray_image = double(a);
                    [rows,cols]=size(gray_image);
                    mask = [0,-1,0;-1,5,-1;0,-1,0];
                    out = gray_image;
                    for i=2:rows-1
                      for j=2:cols-1
                         temp = mask.*gray_image(i-1:i+1,j-1:j+1);
                         value = sum(temp(:));
                         out(i, j)= value;
                      end
                    end
                    out = uint8(out);
                    a=out;
                    imshow((a),'Parent',app.UIAxes_2)
                    title('sharp image');     
                   counter=counter+1;
                    arr{counter}=a;
                    
                case "Sharpining(Diag Laplacian)"
                    a=arr{counter};
                    gray_image = double(a);
                    [rows,cols]=size(gray_image);
                    mask = [-1,-1,-1;-1,9,-1;-1,-1,-1];
                    out = gray_image;
                    for i=2:rows-1
                      for j=2:cols-1
                         temp = mask.*gray_image(i-1:i+1,j-1:j+1);
                         value = sum(temp(:));
                         out(i, j)= value;
                      end
                    end
                    out = uint8(out);
                    a=out;
                    imshow((a),'Parent',app.UIAxes_2)
                    counter=counter+1;
                    arr{counter}=a;
                    
                case "Sharpining(Robert Vertical)" %25
                    input_image = arr{counter};

                    % Convert the image to double
                    input_image = double(input_image);

                    % Pre-allocate the filtered_image matrix with zeros
                    filtered_image = zeros(size(input_image));

                    % Robert Operator Mask
                    My = [0 -1;1 0];

                    for i = 1:size(input_image, 1) - 1
                        for j = 1:size(input_image, 2) - 1

                            % Gradient approximations
                            Gy = sum(sum(My.*input_image(i:i+1, j:j+1)));

                            % Calculate magnitude of vector
                            filtered_image(i, j) = sqrt(Gy.^2); 
                        end
                    end

                    % Displaying Filtered Image
                    filtered_image = uint8(filtered_image);
                    imshow(filtered_image, 'Parent', app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=filtered_image;
                case "Sharpining(Robert Horizontal)"
                    input_image = arr{counter};

                    % Convert the image to double
                    input_image = double(input_image);

                    % Pre-allocate the filtered_image matrix with zeros
                    filtered_image = zeros(size(input_image));

                    % Robert Operator Mask
                    Mx = [1 0; 0 -1];

                    for i = 1:size(input_image, 1) - 1
                        for j = 1:size(input_image, 2) - 1

                            % Gradient approximations
                            Gx = sum(sum(Mx.*input_image(i:i+1, j:j+1)));

                            % Calculate magnitude of vector
                            filtered_image(i, j) = sqrt(Gx.^2);     
                        end
                    end    
                    % Displaying Filtered Image
                    filtered_image = uint8(filtered_image);
                    imshow(filtered_image, 'Parent', app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=filtered_image;
                case "Sharpening Robert frist dervite "
                    input_image = arr{counter};
                      
                    % Convert the image to double
                    input_image = double(input_image);
                      
                    % Pre-allocate the filtered_image matrix with zeros
                    filtered_image = zeros(size(input_image));
                      
                    % Robert Operator Mask
                    Mx = [1 0; 0 -1];
                    My = [0 1; -1 0];
                    
                    for i = 1:size(input_image, 1) - 1
                        for j = 1:size(input_image, 2) - 1
                            
                            % Gradient approximations
                            Gx = sum(sum(Mx.*input_image(i:i+1, j:j+1)));
                            Gy = sum(sum(My.*input_image(i:i+1, j:j+1)));
                            
                            % Calculate magnitude of vector
                            filtered_image(i, j) = sqrt(Gx.^2 + Gy.^2);
                             
                        end
                    end
                    % Displaying Filtered Image
                    filtered_image = uint8(filtered_image);
                    imshow(filtered_image, 'Parent', app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=filtered_image;
                case "Sharpining(Sobel Vertical)"
                    I=double(arr{counter});
                    In=I;
                    mask2 = [-1 0 1;-2 0 2;-1 0 1];

                    for i=2:size(I, 1)-1
                        for j=2:size(I, 2)-1
                            neighbour_matrix2=mask2.*In(i-1:i+1, j-1:j+1);
                            avg_value2=sum(neighbour_matrix2(:));

                            %using max function for detection of final edges
                            I(i, j)=avg_value2;
                        end
                    end
                    I = uint8(I);
                    imshow(I, 'Parent', app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=I;
                case "Sharpining(Sobel Horizontal)"
                    I=double(arr{counter});
                    In=I;
                    mask1 = [-1 -2 -1;0 0 0;1 2 1];

                    for i=2:size(I, 1)-1
                        for j=2:size(I, 2)-1
                            neighbour_matrix1=mask1.*In(i-1:i+1, j-1:j+1);
                            avg_value1=sum(neighbour_matrix1(:));

                            %using max function for detection of final edges
                            I(i, j)=avg_value1;
                        end
                    end
                    I = uint8(I);
                    imshow(I, 'Parent', app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=I;
                case "Sharpening Sobel frist dervite " %26
                    I=double(arr{counter});
                    In=I;
                    mask1 = [-1 -2 -1;0 0 0;1 2 1];
                    mask2 = [-1 0 1;-2 0 2;-1 0 1];
                    
                    for i=2:size(I, 1)-1
                        for j=2:size(I, 2)-1
                            neighbour_matrix1=mask1.*In(i-1:i+1, j-1:j+1);
                            avg_value1=sum(neighbour_matrix1(:));
                    
                            neighbour_matrix2=mask2.*In(i-1:i+1, j-1:j+1);
                            avg_value2=sum(neighbour_matrix2(:));
                    
                            %using max function for detection of final edges
                            I(i, j)=max([avg_value1, avg_value2]);
                    
                        end
                    end
                    I = uint8(I);
                    imshow(I, 'Parent', app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=I;
                case "High pass filter (ideal both)" %30
                    input_image = arr{counter};    
                    [M, N] = size(input_image);
                   FT_img = fft2(double(input_image));
                   D0 = app.Input1EditField.Value; 
                   H = FT_img;
                  for u=1:1:M
                      for v=1:1:N
                         D = sqrt((u-(M/2))^2 +(v-(N/2))^2);
                         if(D<=D0)
                           H(u,v)=0;
                        else
                           H(u,v)=1;
                        end
                     end
                 end
                 G = H.*FT_img;
                 output_image = real(ifft2(G));
                 imshow(output_image,[ ], 'Parent', app.UIAxes_2);
                 counter=counter+1;
                 arr{counter}=output_image;
                case "High pass filter (Butterworth both)" %31
                    input_image = arr{counter};  
                    [M, N] = size(input_image);
                    % Compute the 2D FFT of the input image
                    FT_img = fft2(double(input_image));
                    D0 = app.Input1EditField.Value; %inp
                    n = app.Input2EditField.Value; %inp
                    H = FT_img;
                    for u = 1:M
                       for v = 1:N
                          D = sqrt((u-(M/2))^2 +(v-(N/2))^2);
                          H(u,v)=1/1+((D0/D)^n);
                      end
                    end
                   G = H .* FT_img;
                  % Compute the inverse Fourier transform to obtain the filtered image
                    output_image = real(ifft2(G));
                    imshow(output_image,[ ], 'Parent', app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=output_image;                
                    case "High pass filter (gaussian both)" %32
                    input_image = arr{counter};  
                    [M, N] = size(input_image);
                    % Compute the 2D FFT of the input image
                    FT_img = fft2(double(input_image));
                    D0 = app.Input1EditField.Value; %inp
                    H = FT_img;
                    for u = 1:M
                       for v = 1:N
                          D = sqrt((u-(M/2))^2 +(v-(N/2))^2);
                           H(u,v)=1-exp((-D.^2)/((D0^2)*2));
                      end
                    end
                   G = H .* FT_img;
                  % Compute the inverse Fourier transform to obtain the filtered image
                    output_image = real(ifft2(G));
                    imshow(output_image,[ ], 'Parent', app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=output_image;   
                case "Low pass filter (ideal both)" %30
                    input_image = arr{counter};    
                    [M, N] = size(input_image);
                   FT_img = fft2(double(input_image));
                   D0 = app.Input1EditField.Value; 
                   H = FT_img;
                  for u=1:1:M
                      for v=1:1:N
                         D = sqrt((u-(M/2))^2 +(v-(N/2))^2);
                         if(D<=D0)
                           H(u,v)=1;
                        else
                           H(u,v)=0;
                        end
                     end
                 end
                 G = H.*FT_img;
                 output_image = real(ifft2(G));
                 imshow(output_image,[ ], 'Parent', app.UIAxes_2);
                 counter=counter+1;
                 arr{counter}=output_image;
                case "Low pass filter (Butterworth both)" %31
                    input_image = arr{counter};  
                    [M, N] = size(input_image);
                    % Compute the 2D FFT of the input image
                    FT_img = fft2(double(input_image));
                    D0 = app.Input1EditField.Value; %inp
                    n = app.Input2EditField.Value; %inp
                    H = FT_img;
                    for u = 1:M
                       for v = 1:N
                          D = sqrt((u-(M/2))^2 +(v-(N/2))^2);
                          H(u,v)=1/1+((D/D0)^n);
                      end
                    end
                   G = H .* FT_img;
                  % Compute the inverse Fourier transform to obtain the filtered image
                    output_image = real(ifft2(G));
                    imshow(output_image,[ ], 'Parent', app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=output_image;                
                    case "Low pass filter (gaussian both)" %32
                    input_image = arr{counter};  
                    [M, N] = size(input_image);
                    % Compute the 2D FFT of the input image
                    FT_img = fft2(double(input_image));
                    D0 = app.Input1EditField.Value; %inp
                    n = app.Input2EditField.Value; %inp
                    H = FT_img;
                    for u = 1:M
                       for v = 1:N
                          D = sqrt((u-(M/2))^2 +(v-(N/2))^2);
                           H(u,v)=exp((-D.^2)/((D0^2)*2));
                      end
                    end
                   G = H .* FT_img;
                  % Compute the inverse Fourier transform to obtain the filtered image
                    output_image = real(ifft2(G));
                    imshow(output_image,[ ], 'Parent', app.UIAxes_2);
                    counter=counter+1;
                    arr{counter}=output_image;   
            end

        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
             global arr;
             global counter;
            if(counter>=2)
                arr{counter}=0;
                counter = counter-1;
                imshow(arr{counter}, 'Parent', app.UIAxes_2);
                if(counter>1)
                    imshow(arr{counter-1}, 'Parent', app.UIAxes);
                end
            end
        end

        % Button pushed function: RGBTOGRAYButton
        function RGBTOGRAYButtonPushed(app, event)
            global image;
            global arr;
            global counter;
            try
                image = rgb2gray(arr{counter});
                imshow(image, 'Parent', app.UIAxes_2);
                counter = counter + 1;
                arr{counter}=image;
            catch
                uialert(app.UIFigure,"The image is already gray",'Invalid choice');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Edited Image')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.FiltersDropDownLabel = uilabel(app.UIFigure);
            app.FiltersDropDownLabel.Text = 'Filters';
            app.FiltersDropDown = uidropdown(app.UIFigure);
            app.FiltersDropDown.Items = {'Upsampling', 'Subsampling', 'Smoothing(Avg Standerd)', 'Sharpining(Diag Laplacian)', 'Sharpining(Robert Horizontal)', 'Sharpining(Robert Vertical)', 'Sharpining(Sobel Horizontal)', 'Sharpining(Sobel Vertical)', ' Gray slicing AP#1', ' Gray slicing AP#2', 'negative', 'Log', 'inverse Log', 'Power Low', 'Contrast stretching', 'Theashould 0,1', 'Bit plane slicing', 'Addition', 'Subtraction ', 'Image Subtraction ', 'Multiplication', 'Division', 'Logic AND', 'Logic OR', 'Histogram', 'Histogram Equalization', 'Smoothing(Avg Weight)', 'Smoothing(Median)', 'Sharpining(Laplacian)', 'Sharpining(Comp Laplacian)', 'Sharpening Robert frist dervite ', 'Sharpening Sobel frist dervite ', 'Low pass filter (ideal both)', 'Low pass filter (Butterworth both)', 'Low pass filter (gaussian both)', 'High pass filter (ideal both)', 'High pass filter (Butterworth both)', 'High pass filter (gaussian both)', 'Grey Level Resolution', 'Smoothing(Mod)'};
            app.FiltersDropDown.ValueChangedFcn = createCallbackFcn(app, @FiltersDropDownValueChanged, true);
            app.FiltersDropDown.Value = 'Upsampling';
            app.UPLOADButton = uibutton(app.UIFigure, 'push');
            app.UPLOADButton.ButtonPushedFcn = createCallbackFcn(app, @UPLOADButtonPushed, true);
            app.UPLOADButton.Text = 'UPLOAD';
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.Input2EditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.Input1EditField = uieditfield(app.UIFigure, 'numeric');
            app.APPLYButton = uibutton(app.UIFigure, 'push');
            app.APPLYButton.ButtonPushedFcn = createCallbackFcn(app, @APPLYButtonPushed, true);
            app.APPLYButton.Text = 'APPLY';
            app.RGBTOGRAYButton = uibutton(app.UIFigure, 'push');
            app.RGBTOGRAYButton.ButtonPushedFcn = createCallbackFcn(app, @RGBTOGRAYButtonPushed, true);
            app.RGBTOGRAYButton.Text = 'RGBTOGRAY';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Omar

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end