classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        inputBEditField             matlab.ui.control.NumericEditField
        inputBEditFieldLabel        matlab.ui.control.Label
        inputAEditField             matlab.ui.control.NumericEditField
        inputAEditFieldLabel        matlab.ui.control.Label
        grayscaleresolutionAButton  matlab.ui.control.Button
        multiplicationAButton       matlab.ui.control.Button
        divitionAButton             matlab.ui.control.Button
        robertallButton             matlab.ui.control.Button
        sobalallButton              matlab.ui.control.Button
        robert2Button               matlab.ui.control.Button
        subtractionAButton          matlab.ui.control.Button
        sobel2Button                matlab.ui.control.Button
        HistogramdiagramButton      matlab.ui.control.Button
        graylevelslicing1ABButton   matlab.ui.control.Button
        inverselogAButton           matlab.ui.control.Button
        negativeButton              matlab.ui.control.Button
        resampllingAButton          matlab.ui.control.Button
        DownsamplingAButton         matlab.ui.control.Button
        avaragefilterButton         matlab.ui.control.Button
        weightfilterButton          matlab.ui.control.Button
        graylevelslicing2ABButton   matlab.ui.control.Button
        HistogramButton             matlab.ui.control.Button
        andButton                   matlab.ui.control.Button
        subtractionButton           matlab.ui.control.Button
        bitplanslicingAButton       matlab.ui.control.Button
        orButton                    matlab.ui.control.Button
        LogAButton                  matlab.ui.control.Button
        robert1Button               matlab.ui.control.Button
        sobel1Button                matlab.ui.control.Button
        contraststrechingButton     matlab.ui.control.Button
        medianfilterButton          matlab.ui.control.Button
        laplaciandaignonalButton    matlab.ui.control.Button
        thersholdAButton            matlab.ui.control.Button
        laplacienButton             matlab.ui.control.Button
        compositelaplacienButton    matlab.ui.control.Button
        additionAButton             matlab.ui.control.Button
        powerlogABButton            matlab.ui.control.Button
        GHPFAButton                 matlab.ui.control.Button
        BHPFAButton                 matlab.ui.control.Button
        IHPFAButton                 matlab.ui.control.Button
        GLPFAButton                 matlab.ui.control.Button
        ILPFAButton                 matlab.ui.control.Button
        BLPFAButton                 matlab.ui.control.Button
        ResetButton                 matlab.ui.control.Button
        RGBGrayButton               matlab.ui.control.Button
        UploadButton                matlab.ui.control.Button
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            global a;
            [filename1,filepath1] = uigetfile({'*.*'},'Select Data File 1');
             cd(filepath1);
            a=imread([filepath1 filename1]);
            imshow( a, 'Parent', app.UIAxes);
        end

        % Callback function
        function ListBoxValueChanged(app, event)

        end

        % Callback function
        function DropDownValueChanged(app, event)

        end

        % Callback function
        function doneButtonPushed(app, event)
            switch app.DropDown.Value
                case "import"
                     [filename1,filepath1] = uigetfile({'*.*'},'Select Data File 1');
                    cd(filepath1);
                    image=imread([filepath1 filename1]);
                    imshow( image, 'Parent', app.UIAxes);


            end
        end

        % Button pushed function: RGBGrayButton
        function RGBGrayButtonPushed(app, event)
            global x;
            global a;
            x=rgb2gray(a);
            a=x;
            imshow(a,'Parent',app.UIAxes2);

        end

        % Button pushed function: powerlogABButton
        function powerlogABButtonPushed(app, event)
            global a;
            global oldStatus;
            oldStatus=a;
            global value;
            global value2;
            x1=double(a);
            y = value * power(x1, value2);
            imshow((y),[],'Parent',app.UIAxes2);

        end

        % Button pushed function: bitplanslicingAButton
        function bitplanslicingAButtonPushed(app, event)

            global a;
            global oldStatus;
            oldStatus=a;
            global value;
            [rows cols] = size(a);
            k=value;
            newImage = zeros(rows,cols,k);
            for row_index=1:1:rows
                for col_index=1:1:cols
                    newImage(row_index,col_index,k)=bitget(a(row_index,col_index),k);
                end
            end
            a=newImage(:,:,k);
            imshow(a, 'Parent', app.UIAxes2);

        end

        % Button pushed function: DownsamplingAButton
        function DownsamplingAButtonPushed(app, event)
            function [outImage] = subSampling(image, subSamplingFactor)
            [rows cols] = size(image);
            outImage = image(1:subSamplingFactor:rows,1:subSamplingFactor:cols);
            end
            global a;
            global oldStatus;
            oldStatus=a;
            global value;
                    [rows, cols, matricesNo] = size(a);
                    for metricesIndex=1:1:matricesNo
                        resizedImage(:,:,metricesIndex) = subSampling(a(:,:,metricesIndex),value);
                    end
            imshow((a),'Parent',app.UIAxes2)
        end

        % Button pushed function: resampllingAButton
        function resampllingAButtonPushed(app, event)
             global a;
             global oldStatus;
             global value;
             oldStatus=a;
                    [rows, cols, channels] = size(a);
                    outRows = rows * value;
                    outCols = cols * value;
                    outImage = zeros(outRows, outCols, channels, class(a));
                    for k = 1:channels
                        for i = 1:outRows
                            for j = 1:outCols
                                inputRow = round(i/value);
                                inputCol = round(j/value);
                                inputRow = min(max(inputRow, 1), rows);
                                inputCol = min(max(inputCol, 1), cols);
                                outImage(i, j, k) = a(inputRow, inputCol, k);
                            end
                        end
                    end   
                    imshow(outImage, 'Parent', app.UIAxes2);

        end

        % Button pushed function: andButton
        function andButtonPushed(app, event)
            global a;
            global oldStatus;
            oldStatus=a;
           [filename1,filepath1] = uigetfile({'*.*'},'Select Data File 1');
            cd (filepath1);
            c=imread([filepath1 filename1]);
            c=rgb2gray(c);
            a=rgb2gray(a);
            [m, n] = size(a);
            and_image = zeros(m, n, 'uint8');
            for i = 1:m
                for j = 1:n
                    and_image(i,j) = bitand( a(i,j), c(i,j));
                end
            end
            % f=imresize(a,[size(c,1) size(c,2)]);
            % Combine the images using logical AND
            % img_and = f & c;
            a=and_image;
            imshow((a),'Parent',app.UIAxes2)


            % Create a new image to store the result


            % Iterate over each pixel in the images and perform the AND operation


        end

        % Button pushed function: negativeButton
        function negativeButtonPushed(app, event)
            global a;
            global oldStatus;
            oldStatus=a;
            % % levels of the 8-bit image
            L = 2 ^ 8;

            % finding the negative
            neg = (L - 1) - a;

            % displaying the negative image
            a=neg;
            imshow((a),'Parent',app.UIAxes2);
        end

        % Button pushed function: inverselogAButton
        function inverselogAButtonPushed(app, event)
            global a;
            global oldStatus;
            oldStatus=a;
            global value;
            exp_I = uint8((exp(double(a)) .^ (log(256) / (255))) - 1);
            imshow((exp_I),'Parent',app.UIAxes2);

        end

        % Button pushed function: LogAButton
        function LogAButtonPushed(app, event)
            global a;
            global oldStatus;
            oldStatus=a;
            log_I = uint8(log(double(a)+1) .* ((255)/log(256)));
            imshow((log_I),'Parent',app.UIAxes2);
        end

        % Button pushed function: graylevelslicing1ABButton
        function graylevelslicing1ABButtonPushed(app, event)

            global a;
            global oldStatus;
            oldStatus=a;
            newImage = a;
            global value;
            global value2;
            [rows cols] = size(a);
            for row_index=1:1:rows
                for col_index=1:1:cols
                    if(a(row_index,col_index)>=value && a(row_index,col_index)<=value2)
                        newImage(row_index,col_index) = 255;
                    else
                        newImage(row_index,col_index) = 0;
                    end
                end
            end
            a=newImage;
            imshow((a),'Parent',app.UIAxes2);
        end

        % Button pushed function: graylevelslicing2ABButton
        function graylevelslicing2ABButtonPushed(app, event)
            global a;
            global oldStatus;
            oldStatus=a;
            newImage = a;
            global value;
            global value2;
            [rows cols] = size(a);
            for row_index=1:1:rows
                for col_index=1:1:cols
                    if(a(row_index,col_index)>=value && a(row_index,col_index)<=value2)
                        newImage(row_index,col_index) = 255;
                    else
                        newImage(row_index,col_index) = a(row_index,col_index);
                    end
                end
            end
            a=newImage;
            imshow((a),'Parent',app.UIAxes2);
        end

        % Button pushed function: thersholdAButton
        function thersholdAButtonPushed(app, event)
            global a;
            global oldStatus;
            oldStatus=a;
            global value;
            [rows cols] = size(a);
            for row_index=1:1:rows
                for col_index=1:1:cols
                    if(a(row_index,col_index)>= value )
                        a(row_index,col_index) = 255;
                    else
                        a(row_index,col_index) = 0;
                    end
                end
            end
            a
            imshow((a),'Parent',app.UIAxes2);
        end

        % Button pushed function: contraststrechingButton
        function contraststrechingButtonPushed(app, event)
            global a;
            global oldStatus;
            oldStatus=a;
            maxx=max(max(a(:,:)));
            minn=min(min(a(:,:)));
            s=((255-0)/(maxx-minn))*(a-minn);
            a=s;
            imshow((a),'Parent',app.UIAxes2);
        end

        % Button pushed function: orButton
        function orButtonPushed(app, event)
            global a;
            global oldStatus;
            oldStatus=a;
            [filename1,filepath1] = uigetfile({'*.*'},'Select Data File 1');
            cd(filepath1);
            image1 = imread([filepath1 filename1]);
            a=rgb2gray(a);
            [m, n] = size(image1);
            [mm, nn] = size(a);
            new = a;
            for i = 1:min(mm,m)
                for j =1:min(nn,n)
                    if (a(i,j)>image1(i,j))
                        new(i,j)=a(i,j);
                    else
                        new(i,j)=image1(i,j);
                    end
                end
            end
            imshow((new),'Parent',app.UIAxes2)
        end

        % Button pushed function: HistogramButton
        function HistogramButtonPushed(app, event)
            global a;
            global oldStatus;
            oldStatus=a;
            numofpixels=size(a,1)*size(a,2);

            HIm=uint8(zeros(size(a,1),size(a,2)));
            freq=zeros(256,1);
            probc=zeros(256,1);
            output=zeros(256,1);
            %freq counts the occurrence of each pixel value.
            %The probability of each occurrence is calculated by probf.
            for i=1:size(a,1)
                for j=1:size(a,2)
                    value=a(i,j);
                    freq(value+1)=freq(value+1)+1;
                end
            end
            sum=0;
            no_bins=255;
            %The cumulative distribution probability is calculated.
            for i=1:size(freq)
                sum=sum+freq(i);
                probc(i)=sum/numofpixels;
                output(i)=round(probc(i)*no_bins);
            end
            for i=1:size(a,1)
                for j=1:size(a,2)
                    HIm(i,j)=output(a(i,j)+1);
                end
            end
            a=HIm;
            imshow((a),'Parent',app.UIAxes2)
        end

        % Button pushed function: avaragefilterButton
        function avaragefilterButtonPushed(app, event)
            global a;
            global oldStatus;
            oldStatus=a;
            gray_image = double(a);
            [rows,cols]=size(gray_image);
            n=3;
            out = gray_image;
            for i=2:rows-1
                for j=2:cols-1
                    temp = gray_image(i-1:i+1,j-1:j+1);
                    value = sum(temp(:))*1/(n*n);
                    out(i, j)= value;
                end
            end
            out = uint8(out);
            a=out;
            imshow((a),'Parent',app.UIAxes2);
        end

        % Button pushed function: medianfilterButton
        function medianfilterButtonPushed(app, event)
            global a;
            global oldStatus;
            oldStatus=a;
            [rows,cols]=size(a);
            out=a;
            for i=2:rows-1
                for j=2:cols-1
                    temp = a(i-1:i+1,j-1:j+1);
                    temp = sort(temp);
                    out(i, j)= temp(5);
                end
            end
            a=out;
            imshow((a),'Parent',app.UIAxes2);
        end

        % Button pushed function: subtractionButton
        function subtractionButtonPushed(app, event)
            global a;
            global e;
            global oldStatus;
            oldStatus=a;
            [filename1,filepath1] = uigetfile({'*.*'},'Select Data File 1');
            cd(filepath1);
            e=imread([filepath1 filename1]);
            Image1 = rgb2gray(a);
            Image2 = rgb2gray(e);
            f=imresize(Image1,[size(Image2,1) size(Image2,2)]);
            answer = f-Image2;
            a=answer;
            imshow((a),'Parent',app.UIAxes2);

        end

        % Button pushed function: HistogramdiagramButton
        function HistogramdiagramButtonPushed(app, event)
            global a;
            global oldStatus;
            oldStatus=a;
            [rows,cols]=size(a);
            counts=zeros(1,256);
            for i=1:rows
                for j=1:cols
                    grayLevel=a(i,j);
                    counts(grayLevel+1)=counts(grayLevel+1)+1;
                end
            end
            grayLevels = 0 : 255;
            bar(app.UIAxes2,grayLevels, counts);
            xlabel(app.UIAxes2,'Gray Level', 'FontSize', 12);
            ylabel(app.UIAxes2,'Pixel Count', 'FontSize', 12);
            title(app.UIAxes2,'Histogram', 'FontSize', 14);
            grid on;
        end

        % Button pushed function: weightfilterButton
        function weightfilterButtonPushed(app, event)

            global a;
            global oldStatus;
            oldStatus=a;
            gray_image = double(a);
            [rows,cols]=size(gray_image);
            mask = [(1/16),(2/16),(1/16);(2/16),(4/16),(2/16);(1/16),(2/16),(1/16)];
            out = gray_image;
            for i=2:rows-1
                for j=2:cols-1
                    temp = mask.*gray_image(i-1:i+1,j-1:j+1);
                    value = sum(temp(:));
                    out(i, j)= value;
                end
            end
            out = uint8(out);
            a=out;
            imshow((a),'Parent',app.UIAxes2)
        end

        % Button pushed function: sobel1Button
        function sobel1ButtonPushed(app, event)
            global a;
            global oldStatus;
            oldStatus=a;
            gray_image = double(a);
            [rows,cols]=size(gray_image);
            mask = [-1 -2 -1;0 0 0;1 2 1];
            out = gray_image;
            for i=2:rows-1
                for j=2:cols-1
                    temp = mask.*gray_image(i-1:i+1,j-1:j+1);
                    value = sum(temp(:));
                    out(i, j)= value;
                end
            end
            out = uint8(out);
            a=out;
            imshow((a),'Parent',app.UIAxes2);
        end

        % Button pushed function: sobel2Button
        function sobel2ButtonPushed(app, event)
            global a;
            global oldStatus;
            oldStatus=a;
            gray_image = double(a);
            [rows,cols]=size(gray_image);
            mask = [-1 0 1;-2 0 2;-1 0 1];
            out = gray_image;
            for i=2:rows-1
                for j=2:cols-1
                    temp = mask.*gray_image(i-1:i+1,j-1:j+1);
                    value = sum(temp(:));
                    out(i, j)= value;
                end
            end
            out = uint8(out);
            a=out;
            imshow((a),'Parent',app.UIAxes2);

        end

        % Button pushed function: laplacienButton
        function laplacienButtonPushed(app, event)
            global a;
            global oldStatus;
            oldStatus=a;
            gray_image = double(a);
            [rows,cols]=size(gray_image);
            mask = [0,1,0;1,-4,1;0,1,0];
            out = gray_image;
            for i=2:rows-1
                for j=2:cols-1
                    temp = mask.*gray_image(i-1:i+1,j-1:j+1);
                    value = sum(temp(:));
                    out(i, j)= value;
                end
            end
            out = uint8(out);
            a=out;
            imshow((a),'Parent',app.UIAxes2)
            title('sharp image');
        end

        % Button pushed function: compositelaplacienButton
        function compositelaplacienButtonPushed(app, event)
            global a;
            global oldStatus;
            oldStatus=a;
            gray_image = double(a);
            [rows,cols]=size(gray_image);
            mask = [0,-1,0;-1,5,-1;0,-1,0];
            out = gray_image;
            for i=2:rows-1
                for j=2:cols-1
                    temp = mask.*gray_image(i-1:i+1,j-1:j+1);
                    value = sum(temp(:));
                    out(i, j)= value;
                end
            end
            out = uint8(out);
            a=out;
            imshow((a),'Parent',app.UIAxes2)
            title('sharp image');
        end

        % Button pushed function: laplaciandaignonalButton
        function laplaciandaignonalButtonPushed(app, event)
            global a;
            global oldStatus;
            oldStatus=a;
            gray_image = double(a);
            [rows,cols]=size(gray_image);
             mask = [0,1,0;1,-4,1;0,1,0];
            out = gray_image;
            for i=2:rows-1
                for j=2:cols-1
                    temp = mask.*gray_image(i-1:i+1,j-1:j+1);
                    value = sum(temp(:));
                    out(i, j)= value +gray_image();
                end
            end
            out = uint8(out);
            a=out;
            imshow((a),'Parent',app.UIAxes2)
            title('sharp image');
        end

        % Button pushed function: robert1Button
        function robert1ButtonPushed(app, event)
            global a;

            % Convert the image to double
            input_image = double(a);

            % Pre-allocate the filtered_image matrix with zeros
            filtered_image = zeros(size(input_image));

            % Robert Operator Mask
            My = [0 -1;1 0];

            for i = 1:size(input_image, 1) - 1
                for j = 1:size(input_image, 2) - 1

                    % Gradient approximations
                    Gy = sum(sum(My.*input_image(i:i+1, j:j+1)));

                    % Calculate magnitude of vector
                    filtered_image(i, j) = sqrt(Gy.^2);
                end
            end

            % Displaying Filtered Image
            filtered_image = uint8(filtered_image);
            a=filtered_image;
            imshow(a, 'Parent', app.UIAxes2);
        end

        % Button pushed function: additionAButton
        function additionAButtonPushed(app, event)
            global a;
            global oldStatus;
            oldStatus=a;
            global value;
            img=a+value;
            a=img;
            imshow((a),'Parent',app.UIAxes2)
        end

        % Button pushed function: subtractionAButton
        function subtractionAButtonPushed(app, event)
            global a;
            global oldStatus;
            oldStatus=a;
            global value;
            img=a-value;
            a=img;
            imshow((a),'Parent',app.UIAxes2)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            global oldStatus;
            global a;
            a=oldStatus;

            imshow((oldStatus),'Parent',app.UIAxes2)
        end

        % Button pushed function: ILPFAButton
        function ILPFAButtonPushed(app, event)
            global a;
            global value;
            global oldStatus;
            oldStatus=a;
            [M, N] = size(a);
            FT_img = fft2(double(a));
            D0 = value; %inp

            % Designing filter
            u = 0:(M-1);
            idx = find(u>M/2);
            u(idx) = u(idx)-M;
            v = 0:(N-1);
            idy = find(v>N/2);
            v(idy) = v(idy)-N;

            [V, U] = meshgrid(v, u);

            % Calculating Euclidean Distance
            D = sqrt(U.^2+V.^2);

            H = double(D <= D0);

            G = H.*FT_img;

            output_image = real(ifft2(double(G)));
            a=output_image;
            imshow(a,[ ], 'Parent', app.UIAxes2);

        end

        % Button pushed function: GLPFAButton
        function GLPFAButtonPushed(app, event)
            global a;
            global value;
            global oldStatus;
            oldStatus=a;
            [M, N] = size(a);
            FT_img = fft2(double(a));
            D0 = value;
            D0 = (D0^2)*2;
            % Designing filter
            u = 0:(M-1);
            idx = find(u>M/2);
            u(idx) = u(idx)-M;
            v = 0:(N-1);
            idy = find(v>N/2);
            v(idy) = v(idy)-N;

            % MATLAB library function meshgrid(v, u) returns
            % 2D grid which contains the coordinates of vectors
            % v and u. Matrix V with each row is a copy
            % of v, and matrix U with each column is a copy of u
            [V, U] = meshgrid(v, u);

            % Calculating Euclidean Distance
            D = sqrt(U.^2+V.^2);

            D = -D.^2;
            % Comparing with the cut-off frequency and
            % determining the filtering mask
            H = exp(D/D0);

            % Convolution between the Fourier Transformed
            % image and the mask
            G = H.*FT_img;

            % Getting the resultant image by Inverse Fourier Transform
            % of the convoluted image using MATLAB library function
            % ifft2 (2D inverse fast fourier transform)
            output_image = real(ifft2(double(G)));
            % Displaying Input Image and Output Image
            a=output_image;
            imshow(a, [ ],'Parent',app.UIAxes2);
        end

        % Button pushed function: BLPFAButton
        function BLPFAButtonPushed(app, event)
            global a;
            global oldStatus;
            oldStatus=a;
            global value;
            [M, N] = size(a);
            FT_img = fft2(double(a));
            D0 = value;
            n=2*2;
            % Designing filter
            u = 0:(M-1);
            idx = find(u>M/2);
            u(idx) = u(idx)-M;
            v = 0:(N-1);
            idy = find(v>N/2);
            v(idy) = v(idy)-N;

            % MATLAB library function meshgrid(v, u) returns
            % 2D grid which contains the coordinates of vectors
            % v and u. Matrix V with each row is a copy
            % of v, and matrix U with each column is a copy of u
            [V, U] = meshgrid(v, u);

            % Calculating Euclidean Distance
            D = sqrt(U.^2+V.^2);

            D = D./ D0;
            % Comparing with the cut-off frequency and
            % determining the filtering mask
            H = 1./((1+D).^n);

            % Convolution between the Fourier Transformed
            % image and the mask
            G = H.*FT_img;

            % Getting the resultant image by Inverse Fourier Transform
            % of the convoluted image using MATLAB library function
            % ifft2 (2D inverse fast fourier transform)
            output_image = real(ifft2(double(G)));
            % Displaying Input Image and Output Image
            a=output_image;
            imshow(a, [ ],'Parent',app.UIAxes2);
        end

        % Value changed function: inputAEditField
        function inputAEditFieldValueChanged(app, event)
            global value;
            value = app.inputAEditField.Value;

        end

        % Button pushed function: sobalallButton
        function sobalallButtonPushed(app, event)
            global a;
            global oldStatus;
            oldStatus=a;

            e = double(a);
            In=e;
            mask1 = [-1 -2 -1;0 0 0;1 2 1];
            mask2 = [-1 0 1;-2 0 2;-1 0 1];

            for i=2:size(e, 1)-1
                for j=2:size(e, 2)-1
                    neighbour_matrix1=mask1.*In(i-1:i+1, j-1:j+1);
                    avg_value1=sum(neighbour_matrix1(:));

                    neighbour_matrix2=mask2.*In(i-1:i+1, j-1:j+1);
                    avg_value2=sum(neighbour_matrix2(:));

                    %using max function for detection of final edges
                    e(i, j)=max([avg_value1, avg_value2]);

                end
            end
            e = uint8(e);
            a=e;
            imshow(a, 'Parent', app.UIAxes2);


        end

        % Button pushed function: robertallButton
        function robertallButtonPushed(app, event)
            global a;
            global oldStatus;
            oldStatus=a;

            % Convert the image to double
            input_image = double(a);

            % Pre-allocate the filtered_image matrix with zeros
            filtered_image = zeros(size(input_image));

            % Robert Operator Mask
            Mx = [1 0; 0 -1];
            My = [0 1; -1 0];

            for i = 1:size(input_image, 1) - 1
                for j = 1:size(input_image, 2) - 1

                    % Gradient approximations
                    Gx = sum(sum(Mx.*input_image(i:i+1, j:j+1)));
                    Gy = sum(sum(My.*input_image(i:i+1, j:j+1)));

                    % Calculate magnitude of vector
                    filtered_image(i, j) = sqrt(Gx.^2 + Gy.^2);

                end
            end
            % Displaying Filtered Image
            out = uint8(filtered_image);
            a=out;
            imshow(a, 'Parent', app.UIAxes2);

        end

        % Button pushed function: IHPFAButton
        function IHPFAButtonPushed(app, event)
            global a;
            global oldStatus;
            oldStatus=a;
            global value;
            [M, N] = size(a);
            FT_img = fft2(double(a));
            D0 = value; %inp

            % Designing filter
            u = 0:(M-1);
            idx = find(u>M/2);
            u(idx) = u(idx)-M;
            v = 0:(N-1);
            idy = find(v>N/2);
            v(idy) = v(idy)-N;

            [V, U] = meshgrid(v, u);

            % Calculating Euclidean Distance
            D = sqrt(U.^2+V.^2);

            % Comparing with the cut-off frequency and
            % determining the filtering mask
            H = double(D >= D0);

            G = H.*FT_img;

            output_image = real(ifft2(double(G)));
            a=output_image;
            imshow(a,[ ], 'Parent', app.UIAxes2);

        end

        % Button pushed function: BHPFAButton
        function BHPFAButtonPushed(app, event)
            global a;
            global oldStatus;
            oldStatus=a;
            global value;
            [M, N] = size(a);
            FT_img = fft2(double(a));
            D0 = value;
            n = 2 * 2;
            % Designing filter
            u = 0:(M-1);
            idx = find(u>M/2);
            u(idx) = u(idx)-M;
            v = 0:(N-1);
            idy = find(v>N/2);
            v(idy) = v(idy)-N;

            [V, U] = meshgrid(v, u);

            D = sqrt(U.^2+V.^2);

            D = D0./ D;

            H = double(1./((1+D).^n));

            G = H.*FT_img;

            output_image = real(ifft2(double(G)));
            a=output_image;
            imshow(a,[ ], 'Parent', app.UIAxes2);

        end

        % Value changed function: inputBEditField
        function inputBEditFieldValueChanged(app, event)
            global value2;
            value2 = app.inputBEditField.Value;

        end

        % Button pushed function: GHPFAButton
        function GHPFAButtonPushed(app, event)
            global a;
            global oldStatus;
            oldStatus=a;
            global value;
            [M, N] = size(a);
            FT_img = fft2(double(a));
            D0 = value;
            D0 = (D0^2)*2;
            % Designing filter
            u = 0:(M-1);
            idx = find(u>M/2);
            u(idx) = u(idx)-M;
            v = 0:(N-1);
            idy = find(v>N/2);
            v(idy) = v(idy)-N;

            [V, U] = meshgrid(v, u);

            D = sqrt(U.^2+V.^2);

            D = -D.^2;

            H = double(1-exp(D/D0));

            G = H.*FT_img;

            output_image = real(ifft2(double(G)));
            a=output_image;
            imshow(a,[ ], 'Parent', app.UIAxes2);
        end

        % Button pushed function: grayscaleresolutionAButton
        function grayscaleresolutionAButtonPushed(app, event)
            global a;
            global oldStatus;
            oldStatus=a;
            global value;
            k = value;

            target_levels = 2^k;
            target_compr_factor = 256 / target_levels;
            reduced_image = uint8(floor(double(a)/256 * target_levels) * target_compr_factor);
            imshow(reduced_image,[0 255], 'Parent', app.UIAxes2);
        end

        % Button pushed function: multiplicationAButton
        function multiplicationAButtonPushed(app, event)
            global a;
            global oldStatus;
            oldStatus=a;
            global value;
            img=a*value;
            a=img;
            imshow((a),'Parent',app.UIAxes2)
        end

        % Button pushed function: divitionAButton
        function divitionAButtonPushed(app, event)
            global a;
            global oldStatus;
            oldStatus=a;
            global value;
            img=a/value;
            a=img;
            imshow((a),'Parent',app.UIAxes2)
        end

        % Button pushed function: robert2Button
        function robert2ButtonPushed(app, event)
            global a;

            % Convert the image to double
            input_image = double(a);

            % Pre-allocate the filtered_image matrix with zeros
            filtered_image = zeros(size(input_image));

            % Robert Operator Mask
            My = [0 -1;1 0];

            for i = 1:size(input_image, 1) - 1
                for j = 1:size(input_image, 2) - 1

                    % Gradient approximations
                    Gy = sum(sum(My.*input_image(i:i+1, j:j+1)));

                    % Calculate magnitude of vector
                    filtered_image(i, j) = sqrt(Gy.^2);
                end
            end

            % Displaying Filtered Image
            filtered_image = uint8(filtered_image);
            a=filtered_image;
            imshow(a, 'Parent', app.UIAxes2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'original image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'filtered image')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = {'Upload'; ''};
            app.RGBGrayButton = uibutton(app.UIFigure, 'push');
            app.RGBGrayButton.ButtonPushedFcn = createCallbackFcn(app, @RGBGrayButtonPushed, true);
            app.RGBGrayButton.Text = 'RGB-Gray';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.BLPFAButton = uibutton(app.UIFigure, 'push');
            app.BLPFAButton.ButtonPushedFcn = createCallbackFcn(app, @BLPFAButtonPushed, true);
            app.BLPFAButton.Text = 'BLPF {A}';
            app.ILPFAButton = uibutton(app.UIFigure, 'push');
            app.ILPFAButton.ButtonPushedFcn = createCallbackFcn(app, @ILPFAButtonPushed, true);
            app.ILPFAButton.Text = ' ILPF {A}';
            app.GLPFAButton = uibutton(app.UIFigure, 'push');
            app.GLPFAButton.ButtonPushedFcn = createCallbackFcn(app, @GLPFAButtonPushed, true);
            app.GLPFAButton.Text = 'GLPF {A}';
            app.IHPFAButton = uibutton(app.UIFigure, 'push');
            app.IHPFAButton.ButtonPushedFcn = createCallbackFcn(app, @IHPFAButtonPushed, true);
            app.IHPFAButton.Text = 'IHPF {A}';
            app.BHPFAButton = uibutton(app.UIFigure, 'push');
            app.BHPFAButton.ButtonPushedFcn = createCallbackFcn(app, @BHPFAButtonPushed, true);
            app.BHPFAButton.Text = 'BHPF {A}';
            app.GHPFAButton = uibutton(app.UIFigure, 'push');
            app.GHPFAButton.ButtonPushedFcn = createCallbackFcn(app, @GHPFAButtonPushed, true);
            app.GHPFAButton.Text = 'GHPF {A}';
            app.powerlogABButton = uibutton(app.UIFigure, 'push');
            app.powerlogABButton.ButtonPushedFcn = createCallbackFcn(app, @powerlogABButtonPushed, true);
            app.powerlogABButton.Text = 'power log {A,B}';
            app.additionAButton = uibutton(app.UIFigure, 'push');
            app.additionAButton.ButtonPushedFcn = createCallbackFcn(app, @additionAButtonPushed, true);
            app.additionAButton.Text = 'addition {A}';
            app.compositelaplacienButton = uibutton(app.UIFigure, 'push');
            app.compositelaplacienButton.ButtonPushedFcn = createCallbackFcn(app, @compositelaplacienButtonPushed, true);
            app.compositelaplacienButton.Text = 'composite laplacien';
            app.laplacienButton = uibutton(app.UIFigure, 'push');
            app.laplacienButton.ButtonPushedFcn = createCallbackFcn(app, @laplacienButtonPushed, true);
            app.laplacienButton.Text = 'laplacien';
            app.thersholdAButton = uibutton(app.UIFigure, 'push');
            app.thersholdAButton.ButtonPushedFcn = createCallbackFcn(app, @thersholdAButtonPushed, true);
            app.thersholdAButton.Text = 'thershold {A}';
            app.laplaciandaignonalButton = uibutton(app.UIFigure, 'push');
            app.laplaciandaignonalButton.ButtonPushedFcn = createCallbackFcn(app, @laplaciandaignonalButtonPushed, true);
            app.laplaciandaignonalButton.Text = 'laplacian daignonal';
            app.medianfilterButton = uibutton(app.UIFigure, 'push');
            app.medianfilterButton.ButtonPushedFcn = createCallbackFcn(app, @medianfilterButtonPushed, true);
            app.medianfilterButton.Text = 'median filter';
            app.contraststrechingButton = uibutton(app.UIFigure, 'push');
            app.contraststrechingButton.ButtonPushedFcn = createCallbackFcn(app, @contraststrechingButtonPushed, true);
            app.contraststrechingButton.Text = 'contrast streching';
            app.sobel1Button = uibutton(app.UIFigure, 'push');
            app.sobel1Button.ButtonPushedFcn = createCallbackFcn(app, @sobel1ButtonPushed, true);
            app.sobel1Button.Text = 'sobel 1';
            app.robert1Button = uibutton(app.UIFigure, 'push');
            app.robert1Button.ButtonPushedFcn = createCallbackFcn(app, @robert1ButtonPushed, true);
            app.robert1Button.Text = 'robert 1';
            app.LogAButton = uibutton(app.UIFigure, 'push');
            app.LogAButton.ButtonPushedFcn = createCallbackFcn(app, @LogAButtonPushed, true);
            app.LogAButton.Text = 'Log {A}';
            app.orButton = uibutton(app.UIFigure, 'push');
            app.orButton.ButtonPushedFcn = createCallbackFcn(app, @orButtonPushed, true);
            app.orButton.Text = 'or';
            app.bitplanslicingAButton = uibutton(app.UIFigure, 'push');
            app.bitplanslicingAButton.ButtonPushedFcn = createCallbackFcn(app, @bitplanslicingAButtonPushed, true);
            app.bitplanslicingAButton.Text = 'bit plan slicing {A}';
            app.subtractionButton = uibutton(app.UIFigure, 'push');
            app.subtractionButton.ButtonPushedFcn = createCallbackFcn(app, @subtractionButtonPushed, true);
            app.subtractionButton.Text = 'subtraction';
            app.andButton = uibutton(app.UIFigure, 'push');
            app.andButton.ButtonPushedFcn = createCallbackFcn(app, @andButtonPushed, true);
            app.andButton.Text = 'and';
            app.HistogramButton = uibutton(app.UIFigure, 'push');
            app.HistogramButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramButtonPushed, true);
            app.HistogramButton.Text = 'Histogram';
            app.graylevelslicing2ABButton = uibutton(app.UIFigure, 'push');
            app.graylevelslicing2ABButton.ButtonPushedFcn = createCallbackFcn(app, @graylevelslicing2ABButtonPushed, true);
            app.graylevelslicing2ABButton.Text = 'gray level slicing 2 {A,B}';
            app.weightfilterButton = uibutton(app.UIFigure, 'push');
            app.weightfilterButton.ButtonPushedFcn = createCallbackFcn(app, @weightfilterButtonPushed, true);
            app.weightfilterButton.Text = ' weight-filter';
            app.avaragefilterButton = uibutton(app.UIFigure, 'push');
            app.avaragefilterButton.ButtonPushedFcn = createCallbackFcn(app, @avaragefilterButtonPushed, true);
            app.avaragefilterButton.Text = 'avarage filter';
            app.DownsamplingAButton = uibutton(app.UIFigure, 'push');
            app.DownsamplingAButton.ButtonPushedFcn = createCallbackFcn(app, @DownsamplingAButtonPushed, true);
            app.DownsamplingAButton.Text = 'Downsampling {A}';
            app.resampllingAButton = uibutton(app.UIFigure, 'push');
            app.resampllingAButton.ButtonPushedFcn = createCallbackFcn(app, @resampllingAButtonPushed, true);
            app.resampllingAButton.Text = 'resamplling {A}';
            app.negativeButton = uibutton(app.UIFigure, 'push');
            app.negativeButton.ButtonPushedFcn = createCallbackFcn(app, @negativeButtonPushed, true);
            app.negativeButton.Text = 'negative';
            app.inverselogAButton = uibutton(app.UIFigure, 'push');
            app.inverselogAButton.ButtonPushedFcn = createCallbackFcn(app, @inverselogAButtonPushed, true);
            app.inverselogAButton.Text = 'inverse log {A}';
            app.graylevelslicing1ABButton = uibutton(app.UIFigure, 'push');
            app.graylevelslicing1ABButton.ButtonPushedFcn = createCallbackFcn(app, @graylevelslicing1ABButtonPushed, true);
            app.graylevelslicing1ABButton.Text = 'gray level slicing 1{A,B}';
            app.HistogramdiagramButton = uibutton(app.UIFigure, 'push');
            app.HistogramdiagramButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramdiagramButtonPushed, true);
            app.HistogramdiagramButton.Text = 'Histogram diagram';
            app.sobel2Button = uibutton(app.UIFigure, 'push');
            app.sobel2Button.ButtonPushedFcn = createCallbackFcn(app, @sobel2ButtonPushed, true);
            app.sobel2Button.Text = 'sobel 2';
            app.subtractionAButton = uibutton(app.UIFigure, 'push');
            app.subtractionAButton.ButtonPushedFcn = createCallbackFcn(app, @subtractionAButtonPushed, true);
            app.subtractionAButton.Text = 'subtraction {A}';
            app.robert2Button = uibutton(app.UIFigure, 'push');
            app.robert2Button.ButtonPushedFcn = createCallbackFcn(app, @robert2ButtonPushed, true);
            app.robert2Button.Text = 'robert 2';
            app.sobalallButton = uibutton(app.UIFigure, 'push');
            app.sobalallButton.ButtonPushedFcn = createCallbackFcn(app, @sobalallButtonPushed, true);
            app.sobalallButton.Text = 'sobal all';
            app.robertallButton = uibutton(app.UIFigure, 'push');
            app.robertallButton.ButtonPushedFcn = createCallbackFcn(app, @robertallButtonPushed, true);
            app.robertallButton.Text = 'robert all';
            app.divitionAButton = uibutton(app.UIFigure, 'push');
            app.divitionAButton.ButtonPushedFcn = createCallbackFcn(app, @divitionAButtonPushed, true);
            app.divitionAButton.Text = 'divition {A}';
            app.multiplicationAButton = uibutton(app.UIFigure, 'push');
            app.multiplicationAButton.ButtonPushedFcn = createCallbackFcn(app, @multiplicationAButtonPushed, true);
            app.multiplicationAButton.Text = 'multiplication {A}';
            app.grayscaleresolutionAButton = uibutton(app.UIFigure, 'push');
            app.grayscaleresolutionAButton.ButtonPushedFcn = createCallbackFcn(app, @grayscaleresolutionAButtonPushed, true);
            app.grayscaleresolutionAButton.Text = 'gray scale resolution {A}';
            app.inputAEditFieldLabel = uilabel(app.UIFigure);
            app.inputAEditFieldLabel.Text = 'input A';
            app.inputAEditField = uieditfield(app.UIFigure, 'numeric');
            app.inputAEditField.ValueChangedFcn = createCallbackFcn(app, @inputAEditFieldValueChanged, true);
            app.inputBEditFieldLabel = uilabel(app.UIFigure);
            app.inputBEditFieldLabel.Text = 'input B';
            app.inputBEditField = uieditfield(app.UIFigure, 'numeric');
            app.inputBEditField.ValueChangedFcn = createCallbackFcn(app, @inputBEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end